classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FontNameDropDown               matlab.ui.control.DropDown
        FontNameLabel                  matlab.ui.control.Label
        SetPreferencesButton           matlab.ui.control.Button
        FontsizeDropDown               matlab.ui.control.DropDown
        FontsizeDropDownLabel          matlab.ui.control.Label
        BackgroundcolourDropDown       matlab.ui.control.DropDown
        BackgroundcolourDropDownLabel  matlab.ui.control.Label
        FontcolourDropDown             matlab.ui.control.DropDown
        FontcolourDropDownLabel        matlab.ui.control.Label
        SystemArmedLamp                matlab.ui.control.Lamp
        SystemArmedLampLabel           matlab.ui.control.Label
        LOCKEDOUTLabel                 matlab.ui.control.Label
        NameBox                        matlab.ui.control.Label
        LogoutButton                   matlab.ui.control.Button
        SystemTabs                     matlab.ui.container.TabGroup
        SystemManagerTab               matlab.ui.container.Tab
        TestSystemConsoleTextArea      matlab.ui.control.TextArea
        TestSystemConsoleLabel         matlab.ui.control.Label
        ArmSystemButton                matlab.ui.control.Button
        AbortStartupButton             matlab.ui.control.Button
        SystemwillarminEditField       matlab.ui.control.NumericEditField
        SystemwillarminEditFieldLabel  matlab.ui.control.Label
        TestSystemButton               matlab.ui.control.Button
        SensorManagerTab               matlab.ui.container.Tab
        UpdateSensorInfoButton         matlab.ui.control.Button
        SensorDataTable                matlab.ui.control.Table
        SensorIndexEditField           matlab.ui.control.NumericEditField
        SensorIndexEditFieldLabel      matlab.ui.control.Label
        ConfirmActionButton_3          matlab.ui.control.Button
        LogicSwitch_2                  matlab.ui.control.Switch
        TypeSwitch_2                   matlab.ui.control.Switch
        PinNumberEditField_3           matlab.ui.control.NumericEditField
        PinNumberEditField_3Label      matlab.ui.control.Label
        NameEditField_3                matlab.ui.control.EditField
        NameEditField_3Label           matlab.ui.control.Label
        AddRemoveSwitch                matlab.ui.control.Switch
        LockManagerTab                 matlab.ui.container.Tab
        UpdateLockInfoButton           matlab.ui.control.Button
        LockDataTable                  matlab.ui.control.Table
        LockIndexEditField             matlab.ui.control.NumericEditField
        LockIndexEditFieldLabel        matlab.ui.control.Label
        NameEditField_4                matlab.ui.control.EditField
        NameEditField_4Label           matlab.ui.control.Label
        ConfirmActionButton_4          matlab.ui.control.Button
        PinNumberEditField_4           matlab.ui.control.NumericEditField
        PinNumberLabel                 matlab.ui.control.Label
        AddRemoveSwitch_5              matlab.ui.control.Switch
        UserManagerTab                 matlab.ui.container.Tab
        MaxAttemptsEditField           matlab.ui.control.NumericEditField
        MaxAttemptsEditFieldLabel      matlab.ui.control.Label
        NewPasswordEditField           matlab.ui.control.EditField
        NewPasswordEditFieldLabel      matlab.ui.control.Label
        UpdateCredentialsButton        matlab.ui.control.Button
        ConfirmActionButton_5          matlab.ui.control.Button
        NameEditField_5                matlab.ui.control.EditField
        NameEditField_5Label           matlab.ui.control.Label
        AddRemoveSwitch_6              matlab.ui.control.Switch
        UserAdminSwitch                matlab.ui.control.Switch
        CheckLoginButton               matlab.ui.control.Button
        LoginEditField                 matlab.ui.control.EditField
        LoginEditFieldLabel            matlab.ui.control.Label
    end

   %%% MATLAB VERSION R2023B 
    properties (Access = private)
        arduino
        abortFlag
        abortTime
        users
        newnet
    end
    
    methods (Access = private)
        function value = CheckPassword(app)
            %code for serial communication with arduino
            message=readline(app.arduino);  %see what state the app.arduino is in, is neccessary to know when logging in, logging out, and if armed/disarmed
            if (string(message) == "Check Login")
                password=app.LoginEditField.Value;
                %Swap to Predict Fucntion when ready to test with Facial
                %recognition

                %username="Nia";

                userID=Predict(50,'s01Test',app.newnet,length(app.users));
                if userID>0
                    username=app.users(userID);
                else 
                    username="Failed Login Attempt";
                end
                disp(userID)
                disp(username)
                writeline(app.arduino,"1"); %if there is a login write 1 to the app.arduino
                app.communicate("Check Username",username); %then communicate to the app.arduino the username and password (NOTE: the username step will be changed when i add facial fully)
                app.communicate("Check Password",password);
                state = readline(app.arduino);
                %if too many inputs it will wait for the arduino to send
                %something that isnt locked
                if(state == "Locked")
                    app.SystemTabs.Visible = "off";
                    app.LoginEditField.Visible = "off";
                    app.CheckLoginButton.Visible = "off";
                    app.LOCKEDOUTLabel.Visible = "on";
                    message=readline(app.arduino);
                    while(message == "System Locked")
                        message=readline(app.arduino);
                    end
                    app.LoginEditField.Visible = "on";
                    app.CheckLoginButton.Visible = "on";
                    app.LOCKEDOUTLabel.Visible = "off";
                end
                message=readline(app.arduino);
                if (message=="User Logged in") %need to add something like activating in 30 seconds, and then activate
                    value = 1;
                    app.NameBox.Visible = "on";
                    app.NameBox.Text = ['Hello '+username,'You are logged in as a User.'];
                    app.SystemArmedLamp.Color = [0.80,0.80,0.80];
                elseif (message=="Admin Logged in") %Admin pannel
                    value = 2;
                    app.NameBox.Visible = "on";
                    app.NameBox.Text = ['Hello '+username,'You are logged in as an Admin.'];
                    app.SystemArmedLamp.Color = [0.80,0.80,0.80];
                else
                    app.NameBox.Visible = "off";
                    value = 0;
                end
            else
                disp(message);
                disp("invalid app.arduino State"); % FOR DEBUGGING
            end
        end
        
        function ArmSystem(app)
            app.SystemwillarminEditField.Visible = "on";
            app.AbortStartupButton.Visible = "on";
            app.SystemwillarminEditFieldLabel.Visible = "on";
            %Countdown stuff
            drawnow;
            startTime = datetime("now");
            currentTime = datetime("now");
            timediff = floor(seconds(currentTime-startTime));
            app.abortFlag=0;
            app.ArmSystemButton.Enable = "off";
            while (timediff < app.abortTime && app.abortFlag == 0)
                countdown = app.abortTime-timediff;
                app.SystemwillarminEditField.Value = countdown;
                currentTime = datetime("now");
                timediff = floor(seconds(currentTime-startTime));
                drawnow;
            end
            app.ArmSystemButton.Enable = "on";
            app.SystemwillarminEditField.Visible = "off";
            app.AbortStartupButton.Visible = "off";
            app.SystemwillarminEditFieldLabel.Visible = "off";
            drawnow;
            if (app.abortFlag == 0)
                %make everything invisible as the system has logged out if
                %it hasnt aborted 
                writeline(app.arduino,"A");
                app.SystemTabs.Visible = "off";
                app.LogoutButton.Visible = "off";
                app.CheckLoginButton.Visible = "on";
                app.LoginEditField.Visible = "on";
                app.LoginEditFieldLabel.Visible = "on";
                app.SystemArmedLamp.Color = [1.00,0.00,0.00];
            end
            
        end

        function TestSystem(app)
            writeline(app.arduino,"T");
            message = readline(app.arduino);
            while(message ~= "Test Complete")   
                disp(message);
                app.TestSystemConsoleTextArea.Value = message;
                message = readline(app.arduino);
            end
        end
        
        function Logout(app)
            app.SystemTabs.Visible = "off";
            app.LogoutButton.Visible = "off";
            app.CheckLoginButton.Visible = "on";
            app.LoginEditField.Visible = "on";
            app.LoginEditFieldLabel.Visible = "on";
            app.NameBox.Visible = "off";
            writeline(app.arduino,"L");
            disp('Logout');
        end
        
        function communicate(app,prompt,info)   %communicates w the app.arduino in a way that avoids errors
            while(1)
                if (string(readline(app.arduino))==prompt)
                    writeline(app.arduino,info);
                    break
                end
            end
        end

        function removeUserFromFacialID(app,userNo,numberOfUsers)   %removes users from the directories
           str = ['s0',int2str(userNo)];
           delete(['photos\',str,'\*.jpg']);
           delete(['croppedfaces\',str,'\*.jpg']);
           for i=userNo:numberOfUsers %did the logic off vibes so could be wrong lmao
               str = ['s0',int2str(i)];
               str1 = ['s0',int2str(i+1)];
               movefile(['photos\',str1,'\*.jpg'],['photos\',str]) %source,destination shifts them one folder backwards
               movefile(['croppedfaces\',str1,'\*.jpg'],['photos\',str]) %source,destination shifts them one folder backwards
           end
        end
        
        function SetupFacialRecognition(app)  %need a way tp add to the arduino too
            app.users=[];
            userCount=input("How many users? "); %CHANGE TO INPUT FIELD
            for i=1:userCount
                app.users(i)=input("Username: ","s");   %CHANGE TO INPUT FIELD
            end
            app.newnet=SimpleFaceRecognition(1,userCount);
            newnet = app.newnet;
            save(newnet);
            for i=(length(app.users)+1:12)
               str = ['s0',int2str(i)];
               delete(['photos\',str,'\*.jpg']);
               delete(['croppedfaces\',str,'\*.jpg']);
            end
        end

        function UpdateCredential(app)
            app.ConfirmActionButton_5.Enable = "off";
            writeline(app.arduino,"U");     %sends update commmand
            AorR= app.AddRemoveSwitch_6.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
            else
                AorR = "R";
            end
            %Communication sequence with arduino to send correct info
            app.communicate("Add or Remove",AorR);
            username = app.NameEditField_5.Value;
            if (strfind(username,',') == [])
                app.NameEditField_5.Placeholder = "";
                app.communicate("Get Username",username); %communicates the neccessary information to identify what is being selected
            else
                app.NameEditField_5.Placeholder = "cannot use comma";
                return
            end
            if AorR=="A"
                app.users(end+1)=username; %adds the new username to the array
                userCount=length(app.users);
                app.newnet=SimpleFaceRecognition(userCount,userCount); %adds a new face at the registered value
                isAdmin = app.UserAdminSwitch.Value;
                %CONVERT ADMIN SWITCH TO 1 or 0
                if (isAdmin == "Admin")
                    isAdmin = "1";
                else
                    isAdmin = "0";
                end
                app.communicate("Get Admin",isAdmin);
            else
                index=find(app.users==username); %finds the index
                if index %if in array
                    app.users(index)=[]; %removes the username
                    app.removeUserFromFacialID(index,length(app.users)); %removes the user
                    app.newnet=SimpleFaceRecognition(0,0); %reruns the facial recognition with the change
                end
            end
            %newnet = app.newnet;
            %save(newnet);
            %MAKE A BOX FOR THIS
            disp(readline(app.arduino));    %display success/error message to user for adding/removing, after the app.arduino has done the computation
            readline(app.arduino);
            app.ConfirmActionButton_5.Enable = "on";
        end
        
        function AddOrRemoveSensor(app)
            writeline(app.arduino,"D");
            AorR= app.AddRemoveSwitch.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
                disp("Adding Sensor")
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","Sensor");
                app.communicate("Pin Number",string(app.PinNumberEditField_3.Value));
                app.communicate("Name",app.NameEditField_3.Value);
                app.communicate("Logic",app.LogicSwitch_2.Value);
                app.communicate("Standard or Pullup",app.TypeSwitch_2.Value);
            else
                disp("removing Sensor")
                AorR = "R";
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","S");
                app.communicate("Sensor Number",string(app.SensorIndexEditField.Value));
            end
            readline(app.arduino)
        end
        
        function AddOrRemoveLock(app)
            writeline(app.arduino,"D");
            AorR= app.AddRemoveSwitch_5.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","Lock");
                app.communicate("Pin Number",app.PinNumberEditField_4.Value);
                app.communicate("Name",app.NameEditField_4.Value);
            else
                AorR = "R";
                %communicate info to matlab
                app.communicate("Add or remove",AorR);
                app.communicate("Sensor or Lock","L");
                app.communicate("Lock Number",app.LockIndexEditField.Value);
            end
            readline(app.arduino)
        end

        %before this set up an if statement reading in a line to check if it
        %matches the start prompt, if so run this function to get all values
        function arr=readUntilStop(app,endMessage)
            arr = string([]);
            message=readline(app.arduino);
            i = 0;
            while (message~=endMessage)
                arr(i+1,1)=i;
                arr(i+1,2)=message;
                i=i+1;
                message=readline(app.arduino);
            end
        end
        
        function colour = ConvertColour(app,colourOption)

            switch colourOption
                case 'Red'
                    colour=[1 0 0];
                case 'Green'
                    colour=[0 1 0];
                case 'Blue'
                    colour=[0 0 1];
                case 'Black'
                    colour=[0 0 0];
                case 'White'
                    colour=[1 1 1];
                case 'Cyan'
                    colour=[0.34,0.98,0.94];
                case 'Yellow'
                    colour=[0.97,0.97,0.61];
                case 'Grey'
                    colour=[0.96,0.96,0.96];
                case 'Pink'
                    colour=[0.97,0.68,1.00]
                otherwise   
                    colour=[0 0 0];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('newnet.mat');
            app.newnet = newnet;
            app.users=["Nia","Mumin"];;
            app.SystemTabs.Visible = "off";
            app.LogoutButton.Visible = "off";
            app.NameBox.Visible = "off";
            app.abortTime = 30;
            app.SystemArmedLamp.Color = [0.80,0.80,0.80];

            clear app.arduino;
            app.arduino = serialport("COM8",9600);
            configureTerminator(app.arduino,"CR/LF");
        end

        % Button pushed function: CheckLoginButton
        function CheckLoginButtonPushed(app, event)
            app.CheckLoginButton.Enable = "off";
            app.LoginEditField.Enable = "off";
            if (isempty(app.LoginEditField.Value))
                value = 0;
            else
                value = app.CheckPassword();
            end
            if (value == 2) %put check if login correct here
                app.SystemTabs.Visible = "on";
                app.SensorManagerTab.Parent = app.SystemTabs; %this code ensures tabs are visible when needed 
                app.LockManagerTab.Parent = app.SystemTabs;
                app.UserManagerTab.Parent = app.SystemTabs;
                app.LogoutButton.Visible = "on";
                app.CheckLoginButton.Visible = "off";
                app.LoginEditField.Visible = "off";
                app.LoginEditFieldLabel.Visible = "off";
            elseif(value == 1)
                app.SystemTabs.Visible = "on";
                app.SensorManagerTab.Parent = [];   %this code effectively hides the tabs as there is not currently a visibility toggle per tabs
                app.LockManagerTab.Parent = [];
                app.UserManagerTab.Parent = [];
                app.LogoutButton.Visible = "on";
                app.CheckLoginButton.Visible = "off";
                app.LoginEditField.Visible = "off";
                app.LoginEditFieldLabel.Visible = "off";
            else
                disp('awww');
            end 
            app.LoginEditField.Value = '';
            app.CheckLoginButton.Enable = "on";
            app.LoginEditField.Enable = "on";
        end

        % Button pushed function: TestSystemButton
        function TestSystemButtonPushed(app, event)
            app.TestSystem();
        end

        % Button pushed function: ArmSystemButton
        function ArmSystemSwitchValueChanged(app, event)
            app.ArmSystem();
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.Logout();
        end

        % Value changed function: AddRemoveSwitch
        function AddRemoveSwitchValueChanged(app, event)
            value = app.AddRemoveSwitch.Value;
            if (value == "Add")
                app.LogicSwitch_2.Visible = "on";
                app.TypeSwitch_2.Visible = "on";
                app.PinNumberEditField_3.Visible = "on";
                app.PinNumberEditField_3Label.Visible = "on";
                app.NameEditField_3.Visible = "on";
                app.NameEditField_3Label.Visible = "on";
                app.SensorIndexEditField.Visible = "off";
                app.SensorIndexEditFieldLabel.Visible = "off";
            else
                app.LogicSwitch_2.Visible = "off";
                app.TypeSwitch_2.Visible = "off";
                app.PinNumberEditField_3.Visible = "off";
                app.PinNumberEditField_3Label.Visible = "off";
                app.NameEditField_3.Visible = "off";
                app.NameEditField_3Label.Visible = "off";
                app.SensorIndexEditField.Visible = "on";
                app.SensorIndexEditFieldLabel.Visible = "on";
            end
        end

        % Button pushed function: AbortStartupButton
        function AbortStartupButtonPushed(app, event)
            app.abortFlag = 1;
        end

        % Value changed function: AddRemoveSwitch_6
        function AddRemoveSwitch_6ValueChanged(app, event)
            value = app.AddRemoveSwitch_6.Value;
            % if the value is Add then make name  and user admin switch
            % visible, if the value is Remove than make only the name
            % visible
            if (value == "Add")
                app.UserAdminSwitch.Visible = "on";
            else
                app.UserAdminSwitch.Visible = "off";
            end

        end

        % Button pushed function: ConfirmActionButton_5
        function ConfirmActionButton_5Pushed(app, event)
            app.UpdateCredential();
        end

        % Button pushed function: ConfirmActionButton_3
        function ConfirmActionButton_3Pushed(app, event)
            app.ConfirmActionButton_3.Enable = "off";  
            app.AddOrRemoveSensor();
            app.ConfirmActionButton_3.Enable = "on";  
        end

        % Button pushed function: ConfirmActionButton_4
        function ConfirmActionButton_4Pushed(app, event)
            app.AddOrRemoveLock();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Value changed function: AddRemoveSwitch_5
        function AddRemoveSwitch_5ValueChanged(app, event)
            value = app.AddRemoveSwitch_5.Value;
            if (value == "Add")
                app.NameEditField_4.Visible = "on";
                app.NameEditField_4Label.Visible = "on";
                app.PinNumberEditField_4.Visible = "on";
                app.LockIndexEditField.Visible = "off";
                app.LockIndexEditFieldLabel.Visible = "off";
            else
                app.NameEditField_4.Visible = "off";
                app.NameEditField_4Label.Visible = "off";
                app.PinNumberEditField_4.Visible = "off";
                app.LockIndexEditField.Visible = "on";
                app.LockIndexEditFieldLabel.Visible = "on";
            end
        end

        % Button pushed function: UpdateSensorInfoButton
        function UpdateSensorInfoButtonPushed(app, event)
            writeline(app.arduino,"SE");
            temp = readline(app.arduino);
            array = [];
            if (temp=="Sensor List Start")
                array = app.readUntilStop("Sensor List End");
            end
            app.SensorDataTable.Data = array;
            readline(app.arduino);
            %writeline(app.arduino,"1");
        end

        % Button pushed function: UpdateLockInfoButton
        function UpdateLockInfoButtonPushed(app, event)
            writeline(app.arduino,"LO");
            temp = readline(app.arduino);
            array = [];
            if (temp=="Lock List Start")
                array = app.readUntilStop("Lock List End");
            end
            app.LockDataTable.Data = array;
            readline(app.arduino);
            %writeline(app.arduino,"1");
        end

        % Button pushed function: UpdateCredentialsButton
        function UpdateCredentialsButtonPushed(app, event)
            app.UpdateCredentialsButton.Enable = "off";
            writeline(app.arduino, "C");
            newpassword = app.NewPasswordEditField.Value
            if (app.NewPasswordEditField.Value == "")
                newpassword = "0";
            end
            app.communicate("New Password", newpassword);

            app.communicate("Attempt Change", string(app.MaxAttemptsEditField.Value));
            app.UpdateCredentialsButton.Enable = "on";
            readline(app.arduino);
        end

        % Value changed function: FontcolourDropDown
        function FontcolourDropDownValueChanged(app, event)
            value = app.FontcolourDropDown.Value;
            colour = app.ConvertColour(value)
            app.SetPreferencesButton.FontColor = colour;
        end

        % Value changed function: BackgroundcolourDropDown
        function BackgroundcolourDropDownValueChanged(app, event)
            value = app.BackgroundcolourDropDown.Value;
            app.SetPreferencesButton.BackgroundColor = app.ConvertColour(value);
        end

        % Value changed function: FontsizeDropDown
        function FontsizeDropDownValueChanged(app, event)
            value = app.FontsizeDropDown.Value;
            
            switch value
                case '12'
                    app.SetPreferencesButton.FontSize=12;
                case '16'
                    app.SetPreferencesButton.FontSize=16;
                case '20'
                    app.SetPreferencesButton.FontSize=20;
            end  
        end

        % Button pushed function: SetPreferencesButton
        function SetPreferencesButtonPushed(app, event)
            backgroundcolour=app.ConvertColour(app.BackgroundcolourDropDown.Value);
            fontcolour=app.ConvertColour(app.FontcolourDropDown.Value);
            fontsize=str2num(app.FontsizeDropDown.Value);
            fontName=app.FontNameDropDown.Value;
            
            set(findall(groot, '-property', 'BackgroundColor'), 'BackgroundColor', backgroundcolour);
            set(findall(groot, '-property', 'FontColor'), 'FontColor', fontcolour);
            set(findall(groot, '-property', 'FontSize'), 'FontSize', fontsize);
            set(findall(groot, '-property', 'FontName'), 'FontName', fontName);
        end

        % Value changed function: FontNameDropDown
        function FontNameDropDownValueChanged(app, event)
            value = app.FontNameDropDown.Value;
            app.SetPreferencesButton.FontName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoginEditFieldLabel = uilabel(app.UIFigure);
            app.LoginEditFieldLabel.Text = 'Login';
            app.LoginEditField = uieditfield(app.UIFigure, 'text');
            app.CheckLoginButton = uibutton(app.UIFigure, 'push');
            app.CheckLoginButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLoginButtonPushed, true);
            app.CheckLoginButton.Text = 'Check Login';
            app.SystemTabs = uitabgroup(app.UIFigure);
            app.SystemManagerTab = uitab(app.SystemTabs);
            app.SystemManagerTab.Title = 'System Manager';
            app.TestSystemButton = uibutton(app.SystemManagerTab, 'push');
            app.TestSystemButton.ButtonPushedFcn = createCallbackFcn(app, @TestSystemButtonPushed, true);
            app.TestSystemButton.Text = 'Test System';
            app.SystemwillarminEditFieldLabel = uilabel(app.SystemManagerTab);
            app.SystemwillarminEditFieldLabel.Visible = 'off';
            app.SystemwillarminEditFieldLabel.Text = 'System will arm in';
            app.SystemwillarminEditField = uieditfield(app.SystemManagerTab, 'numeric');
            app.SystemwillarminEditField.Editable = 'off';
            app.SystemwillarminEditField.Visible = 'off';
            app.AbortStartupButton = uibutton(app.SystemManagerTab, 'push');
            app.AbortStartupButton.ButtonPushedFcn = createCallbackFcn(app, @AbortStartupButtonPushed, true);
            app.AbortStartupButton.Visible = 'off';
            app.AbortStartupButton.Text = 'Abort Arming System';
            app.ArmSystemButton = uibutton(app.SystemManagerTab, 'push');
            app.ArmSystemButton.ButtonPushedFcn = createCallbackFcn(app, @ArmSystemSwitchValueChanged, true);
            app.ArmSystemButton.Text = 'ArmSystem';
            app.TestSystemConsoleLabel = uilabel(app.SystemManagerTab);
            app.TestSystemConsoleLabel.Text = 'Test System Console';
            app.TestSystemConsoleTextArea = uitextarea(app.SystemManagerTab);
            app.SensorManagerTab = uitab(app.SystemTabs);
            app.SensorManagerTab.Title = 'Sensor Manager';
            app.AddRemoveSwitch = uiswitch(app.SensorManagerTab, 'slider');
            app.AddRemoveSwitch.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch.ValueChangedFcn = createCallbackFcn(app, @AddRemoveSwitchValueChanged, true);
            app.AddRemoveSwitch.Value = 'Add';
            app.NameEditField_3Label = uilabel(app.SensorManagerTab);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField_3 = uieditfield(app.SensorManagerTab, 'text');
            app.PinNumberEditField_3Label = uilabel(app.SensorManagerTab);
            app.PinNumberEditField_3Label.Text = 'PinNumber';
            app.PinNumberEditField_3 = uieditfield(app.SensorManagerTab, 'numeric');
            app.TypeSwitch_2 = uiswitch(app.SensorManagerTab, 'slider');
            app.TypeSwitch_2.Items = {'Input', 'Input Pullup'};
            app.TypeSwitch_2.Value = 'Input';
            app.LogicSwitch_2 = uiswitch(app.SensorManagerTab, 'slider');
            app.LogicSwitch_2.Items = {'Positive Logic', 'Negative Logic'};
            app.LogicSwitch_2.Value = 'Positive Logic';
            app.ConfirmActionButton_3 = uibutton(app.SensorManagerTab, 'push');
            app.ConfirmActionButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfirmActionButton_3Pushed, true);
            app.ConfirmActionButton_3.Text = 'Confirm Action';
            app.SensorIndexEditFieldLabel = uilabel(app.SensorManagerTab);
            app.SensorIndexEditFieldLabel.Visible = 'off';
            app.SensorIndexEditFieldLabel.Text = 'SensorIndex';
            app.SensorIndexEditField = uieditfield(app.SensorManagerTab, 'numeric');
            app.SensorIndexEditField.Visible = 'off';
            app.SensorDataTable = uitable(app.SensorManagerTab);
            app.SensorDataTable.ColumnName = {'Index'; 'Sensor'};
            app.SensorDataTable.RowName = {};
            app.UpdateSensorInfoButton = uibutton(app.SensorManagerTab, 'push');
            app.UpdateSensorInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSensorInfoButtonPushed, true);
            app.UpdateSensorInfoButton.Text = 'UpdateSensorInfo';
            app.LockManagerTab = uitab(app.SystemTabs);
            app.LockManagerTab.Title = 'Lock Manager';
            app.AddRemoveSwitch_5 = uiswitch(app.LockManagerTab, 'slider');
            app.AddRemoveSwitch_5.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch_5.ValueChangedFcn = createCallbackFcn(app, @AddRemoveSwitch_5ValueChanged, true);
            app.AddRemoveSwitch_5.Value = 'Add';
            app.PinNumberLabel = uilabel(app.LockManagerTab);
            app.PinNumberLabel.Text = 'PinNumber';
            app.PinNumberEditField_4 = uieditfield(app.LockManagerTab, 'numeric');
            app.ConfirmActionButton_4 = uibutton(app.LockManagerTab, 'push');
            app.ConfirmActionButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConfirmActionButton_4Pushed, true);
            app.ConfirmActionButton_4.Text = 'Confirm Action';
            app.NameEditField_4Label = uilabel(app.LockManagerTab);
            app.NameEditField_4Label.Text = 'Name';
            app.NameEditField_4 = uieditfield(app.LockManagerTab, 'text');
            app.LockIndexEditFieldLabel = uilabel(app.LockManagerTab);
            app.LockIndexEditFieldLabel.Visible = 'off';
            app.LockIndexEditFieldLabel.Text = 'LockIndex';
            app.LockIndexEditField = uieditfield(app.LockManagerTab, 'numeric');
            app.LockIndexEditField.Visible = 'off';
            app.LockDataTable = uitable(app.LockManagerTab);
            app.LockDataTable.ColumnName = {'Index'; 'Lock'};
            app.LockDataTable.RowName = {};
            app.UpdateLockInfoButton = uibutton(app.LockManagerTab, 'push');
            app.UpdateLockInfoButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateLockInfoButtonPushed, true);
            app.UpdateLockInfoButton.Text = 'UpdateLockInfo';
            app.UserManagerTab = uitab(app.SystemTabs);
            app.UserManagerTab.Title = 'User Manager';
            app.UserAdminSwitch = uiswitch(app.UserManagerTab, 'slider');
            app.UserAdminSwitch.Items = {'User', 'Admin'};
            app.UserAdminSwitch.Value = 'User';
            app.AddRemoveSwitch_6 = uiswitch(app.UserManagerTab, 'slider');
            app.AddRemoveSwitch_6.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch_6.ValueChangedFcn = createCallbackFcn(app, @AddRemoveSwitch_6ValueChanged, true);
            app.AddRemoveSwitch_6.Value = 'Add';
            app.NameEditField_5Label = uilabel(app.UserManagerTab);
            app.NameEditField_5Label.Text = 'Name';
            app.NameEditField_5 = uieditfield(app.UserManagerTab, 'text');
            app.ConfirmActionButton_5 = uibutton(app.UserManagerTab, 'push');
            app.ConfirmActionButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConfirmActionButton_5Pushed, true);
            app.ConfirmActionButton_5.Text = 'Confirm Action';
            app.UpdateCredentialsButton = uibutton(app.UserManagerTab, 'push');
            app.UpdateCredentialsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCredentialsButtonPushed, true);
            app.UpdateCredentialsButton.Text = 'UpdateCredentials';
            app.NewPasswordEditFieldLabel = uilabel(app.UserManagerTab);
            app.NewPasswordEditFieldLabel.Text = 'NewPassword';
            app.NewPasswordEditField = uieditfield(app.UserManagerTab, 'text');
            app.MaxAttemptsEditFieldLabel = uilabel(app.UserManagerTab);
            app.MaxAttemptsEditFieldLabel.Text = 'Max Attempts';
            app.MaxAttemptsEditField = uieditfield(app.UserManagerTab, 'numeric');
            app.MaxAttemptsEditField.Value = 3;
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.NameBox = uilabel(app.UIFigure);
            app.LOCKEDOUTLabel = uilabel(app.UIFigure);
            app.LOCKEDOUTLabel.Visible = 'off';
            app.LOCKEDOUTLabel.Text = 'LOCKED OUT';
            app.SystemArmedLampLabel = uilabel(app.UIFigure);
            app.SystemArmedLampLabel.Text = 'System Armed';
            app.SystemArmedLamp = uilamp(app.UIFigure);
            app.FontcolourDropDownLabel = uilabel(app.UIFigure);
            app.FontcolourDropDownLabel.Text = 'Font colour';
            app.FontcolourDropDown = uidropdown(app.UIFigure);
            app.FontcolourDropDown.Items = {'Red', 'Green', 'Blue', 'Black', 'White', 'Cyan', 'Yellow', 'Grey', 'Pink'};
            app.FontcolourDropDown.ValueChangedFcn = createCallbackFcn(app, @FontcolourDropDownValueChanged, true);
            app.FontcolourDropDown.Value = 'Black';
            app.BackgroundcolourDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundcolourDropDownLabel.Text = 'Background colour';
            app.BackgroundcolourDropDown = uidropdown(app.UIFigure);
            app.BackgroundcolourDropDown.Items = {'Red', 'Green', 'Blue', 'Black', 'White', 'Cyan', 'Yellow', 'Grey', 'Pink'};
            app.BackgroundcolourDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundcolourDropDownValueChanged, true);
            app.BackgroundcolourDropDown.Value = 'Grey';
            app.FontsizeDropDownLabel = uilabel(app.UIFigure);
            app.FontsizeDropDownLabel.Text = 'Font size';
            app.FontsizeDropDown = uidropdown(app.UIFigure);
            app.FontsizeDropDown.Items = {'12', '14', '16'};
            app.FontsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @FontsizeDropDownValueChanged, true);
            app.FontsizeDropDown.Value = '12';
            app.SetPreferencesButton = uibutton(app.UIFigure, 'push');
            app.SetPreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @SetPreferencesButtonPushed, true);
            app.SetPreferencesButton.Text = 'Set Preferences';
            app.FontNameLabel = uilabel(app.UIFigure);
            app.FontNameLabel.Text = 'Font Name';
            app.FontNameDropDown = uidropdown(app.UIFigure);
            app.FontNameDropDown.Items = {'Helvetica', 'Arial', 'Comic Sans MS', 'Verdana', 'Century Gothic'};
            app.FontNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FontNameDropDownValueChanged, true);
            app.FontNameDropDown.Value = 'Helvetica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end