classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HelloGeezerLabel               matlab.ui.control.Label
        LogoutButton                   matlab.ui.control.Button
        SystemTabs                     matlab.ui.container.TabGroup
        SystemManagerTab               matlab.ui.container.Tab
        SystemwillarminEditField       matlab.ui.control.NumericEditField
        SystemwillarminEditFieldLabel  matlab.ui.control.Label
        TestSystemButton               matlab.ui.control.Button
        ArmSystemSwitch                matlab.ui.control.Switch
        ArmSystemSwitchLabel           matlab.ui.control.Label
        SensorManagerTab               matlab.ui.container.Tab
        ConfirmActionButton_3          matlab.ui.control.Button
        LogicSwitch_2                  matlab.ui.control.Switch
        TypeSwitch_2                   matlab.ui.control.Switch
        PinNumberEditField_3           matlab.ui.control.NumericEditField
        PinNumberEditField_3Label      matlab.ui.control.Label
        NameEditField_3                matlab.ui.control.EditField
        NameEditField_3Label           matlab.ui.control.Label
        AddRemoveSwitch                matlab.ui.control.Switch
        LockManagerTab                 matlab.ui.container.Tab
        NameEditField_4                matlab.ui.control.EditField
        NameEditField_4Label           matlab.ui.control.Label
        ConfirmActionButton_4          matlab.ui.control.Button
        PinNumberEditField_4           matlab.ui.control.NumericEditField
        PinNumberLabel                 matlab.ui.control.Label
        AddRemoveSwitch_5              matlab.ui.control.Switch
        UserManagerTab                 matlab.ui.container.Tab
        ConfirmActionButton_5          matlab.ui.control.Button
        NameEditField_5                matlab.ui.control.EditField
        NameEditField_5Label           matlab.ui.control.Label
        AddRemoveSwitch_6              matlab.ui.control.Switch
        UserAdminSwitch                matlab.ui.control.Switch
        SensorStatesLabel              matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CheckLoginButton               matlab.ui.control.Button
        LoginEditField                 matlab.ui.control.EditField
        LoginEditFieldLabel            matlab.ui.control.Label
    end

    
    methods (Access = private)
        function value = CheckPassword(app)
            %{
            if (app.LoginEditField.Value == "admin")
                value = 1;
            else if(app.LoginEditField.Value == "user")
                    value = 2;
                else
                    value = 0;
                end
            end
            %}

            %code for serial communicator stuff
            message=readline(arduino);  %see what state the arduino is in, is neccessary to know when logging in, logging out, and if armed/disarmed
            if (message == "Check Login")
                password=app.LoginEditField.Value;
                username=Predict("mumin"); %PLACEHOLDER
                writeline(arduino,"1"); %if there is a login write 1 to the arduino
                communicate(arduino,"Check Username",username) %then communicate to the arduino the username and password (NOTE: the username step will be changed when i add facial fully)
                communicate(arduino,"Check Password",password)
    
                message=readline(arduino);
                if (message=="User Logged in") %need to add something like activating in 30 seconds, and then activate
                    value = 2;
                else if (message=="Admin Logged in") %Admin pannel
                        value = 1;
                    else
                        value = 0;
                    end
                end
            else
                disp("invalid Arduino State") % FOR DEBUGGING
            end
        end
        
        function ArmSystem(app)
            if (app.SensorManagerTab.Parent == [])      %checks if the tab is "viible" if it isnt then its user if it is then its admin
                communicate(arduino,"User Logged In","A");
            else
                communicate(arduino,"Admin Logged In","A");
            end  
            disp(readline(arduino))
        end
        
        function DissarmSystem(app)
            disp('DISSARMED');
        end

        function TestSystem(app)
            communicate(arduino,"Admin Logged In","T");
            disp(readline(arduino));    %display success/error 
            %disp('Testing Beep Boop');
        end
        
        function Logout(app)
            app.SystemTabs.Visible = "off";
            app.LogoutButton.Visible = "off";
            app.CheckLoginButton.Visible = "on";
            app.LoginEditField.Visible = "on";
            app.LoginEditFieldLabel.Visible = "on";
            disp('Logout');
        end
        
        function communicate(arduino,prompt,info)   %communicates w the arduino in a way that avoids errors
            while(1)
                if (readline(arduino)==prompt)
                    writeline(arduino,info);
                    break
                end
            end
        end

        function UpdateCredential(app,arduino)
            AorR= app.AddRemoveSwitch_6.Value;
            %CONVERT TO A or R
            if (AorR == "Add")
                AorR = "A";
            else
                AorR = "R";
            end
            app.communicate(arduino,"Add or Remove",AorR);
            username = app.NameEditField_5.Value;
            app.communicate(arduino,"Get Username",username); %communicates the neccessary information to identify what is being selected
            if AorR=="A"
                isAdmin = app.UserAdminSwitch.Value;
                %CONVERT TO 1 or 0
                if (isAdmin == "Admin")
                    isAdmin = 1;
                else
                    isAdmin = 0;
                end
                app.communicate(arduino,"Get Admin",isAdmin);
            end
            disp(readline(arduino));    %display success/error message to user for adding/removing, after the arduino has done the computation
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SystemTabs.Visible = "off";
            app.LogoutButton.Visible = "off";
            clear arduino;
            arduino = serialport("COM15",9600);
            configureTerminator(arduino,"CR/LF");
        end

        % Button pushed function: CheckLoginButton
        function CheckLoginButtonPushed(app, event)
            if (app.CheckPassword() == 1) %put check if login correct here
                app.SystemTabs.Visible = "on";
                app.SensorManagerTab.Parent = app.SystemTabs; %this code ensures tabs are visible when needed 
                app.LockManagerTab.Parent = app.SystemTabs;
                app.UserManagerTab.Parent = app.SystemTabs;
                app.LogoutButton.Visible = "on";
                app.CheckLoginButton.Visible = "off";
                app.LoginEditField.Visible = "off";
                app.LoginEditFieldLabel.Visible = "off";
                disp('Admin Login');
            else if(app.CheckPassword() == 2)
                    app.SystemTabs.Visible = "on";
                    app.SensorManagerTab.Parent = [];   %this code effectively hides the tabs as there is not currently a visibility toggle per tabs
                    app.LockManagerTab.Parent = [];
                    app.UserManagerTab.Parent = [];
                    app.LogoutButton.Visible = "on";
                    app.CheckLoginButton.Visible = "off";
                    app.LoginEditField.Visible = "off";
                    app.LoginEditFieldLabel.Visible = "off";
                    disp('User Login');
            
                else
                    disp('awww');
                end
            end 
            app.LoginEditField.Value = '';
        end

        % Button pushed function: TestSystemButton
        function TestSystemButtonPushed(app, event)
            app.TestSystem();
        end

        % Value changed function: ArmSystemSwitch
        function ArmSystemSwitchValueChanged(app, event)
            value = app.ArmSystemSwitch.Value;
            if (value == "On")
                app.ArmSystem();
            else
                if (value == "Off")
                    app.DissarmSystem();
                end
            end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            Logout(app);
        end

        % Value changed function: AddRemoveSwitch
        function AddRemoveSwitchValueChanged(app, event)
            value = app.AddRemoveSwitch.Value;
            if (value == "Add")
                app.LogicSwitch_2.Visible = "on";
                app.TypeSwitch_2.Visible = "on";
                app.PinNumberEditField_3.Visible = "on";
                app.NameEditField_3.Visible = "on";
            else
                app.LogicSwitch_2.Visible = "off";
                app.TypeSwitch_2.Visible = "off";
                app.PinNumberEditField_3.Visible = "on";
                app.NameEditField_3.Visible = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoginEditFieldLabel = uilabel(app.UIFigure);
            app.LoginEditFieldLabel.Text = 'Login';
            app.LoginEditField = uieditfield(app.UIFigure, 'text');
            app.CheckLoginButton = uibutton(app.UIFigure, 'push');
            app.CheckLoginButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLoginButtonPushed, true);
            app.CheckLoginButton.Text = 'Check Login';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Sensor'; 'State'};
            app.UITable.RowName = {};
            app.SensorStatesLabel = uilabel(app.UIFigure);
            app.SensorStatesLabel.Text = 'Sensor States';
            app.SystemTabs = uitabgroup(app.UIFigure);
            app.SystemManagerTab = uitab(app.SystemTabs);
            app.SystemManagerTab.Title = 'System Manager';
            app.ArmSystemSwitchLabel = uilabel(app.SystemManagerTab);
            app.ArmSystemSwitchLabel.Text = 'Arm System';
            app.ArmSystemSwitch = uiswitch(app.SystemManagerTab, 'slider');
            app.ArmSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmSystemSwitchValueChanged, true);
            app.TestSystemButton = uibutton(app.SystemManagerTab, 'push');
            app.TestSystemButton.ButtonPushedFcn = createCallbackFcn(app, @TestSystemButtonPushed, true);
            app.TestSystemButton.Text = 'Test System';
            app.SystemwillarminEditFieldLabel = uilabel(app.SystemManagerTab);
            app.SystemwillarminEditFieldLabel.Text = 'System will arm in';
            app.SystemwillarminEditField = uieditfield(app.SystemManagerTab, 'numeric');
            app.SystemwillarminEditField.Editable = 'off';
            app.SensorManagerTab = uitab(app.SystemTabs);
            app.SensorManagerTab.Title = 'Sensor Manager';
            app.AddRemoveSwitch = uiswitch(app.SensorManagerTab, 'slider');
            app.AddRemoveSwitch.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch.ValueChangedFcn = createCallbackFcn(app, @AddRemoveSwitchValueChanged, true);
            app.AddRemoveSwitch.Value = 'Add';
            app.NameEditField_3Label = uilabel(app.SensorManagerTab);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField_3 = uieditfield(app.SensorManagerTab, 'text');
            app.PinNumberEditField_3Label = uilabel(app.SensorManagerTab);
            app.PinNumberEditField_3Label.Text = 'PinNumber';
            app.PinNumberEditField_3 = uieditfield(app.SensorManagerTab, 'numeric');
            app.TypeSwitch_2 = uiswitch(app.SensorManagerTab, 'slider');
            app.TypeSwitch_2.Items = {'Input', 'Input Pullup'};
            app.TypeSwitch_2.Value = 'Input';
            app.LogicSwitch_2 = uiswitch(app.SensorManagerTab, 'slider');
            app.LogicSwitch_2.Items = {'Positive Logic', 'Negative Logic'};
            app.LogicSwitch_2.Value = 'Positive Logic';
            app.ConfirmActionButton_3 = uibutton(app.SensorManagerTab, 'push');
            app.ConfirmActionButton_3.Text = 'Confirm Action';
            app.LockManagerTab = uitab(app.SystemTabs);
            app.LockManagerTab.Title = 'Lock Manager';
            app.AddRemoveSwitch_5 = uiswitch(app.LockManagerTab, 'slider');
            app.AddRemoveSwitch_5.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch_5.Value = 'Add';
            app.PinNumberLabel = uilabel(app.LockManagerTab);
            app.PinNumberLabel.Text = 'PinNumber';
            app.PinNumberEditField_4 = uieditfield(app.LockManagerTab, 'numeric');
            app.ConfirmActionButton_4 = uibutton(app.LockManagerTab, 'push');
            app.ConfirmActionButton_4.Text = 'Confirm Action';
            app.NameEditField_4Label = uilabel(app.LockManagerTab);
            app.NameEditField_4Label.Text = 'Name';
            app.NameEditField_4 = uieditfield(app.LockManagerTab, 'text');
            app.UserManagerTab = uitab(app.SystemTabs);
            app.UserManagerTab.Title = 'User Manager';
            app.UserAdminSwitch = uiswitch(app.UserManagerTab, 'slider');
            app.UserAdminSwitch.Items = {'User', 'Admin'};
            app.UserAdminSwitch.Value = 'User';
            app.AddRemoveSwitch_6 = uiswitch(app.UserManagerTab, 'slider');
            app.AddRemoveSwitch_6.Items = {'Add', 'Remove'};
            app.AddRemoveSwitch_6.Value = 'Add';
            app.NameEditField_5Label = uilabel(app.UserManagerTab);
            app.NameEditField_5Label.Text = 'Name';
            app.NameEditField_5 = uieditfield(app.UserManagerTab, 'text');
            app.ConfirmActionButton_5 = uibutton(app.UserManagerTab, 'push');
            app.ConfirmActionButton_5.Text = 'Confirm Action';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.HelloGeezerLabel = uilabel(app.UIFigure);
            app.HelloGeezerLabel.Text = 'Hello Geezer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end